classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        GloveDefectDetectionSystemLabel  matlab.ui.control.Label
        BrowseForAnImageLabel           matlab.ui.control.Label
        BrowseButton                    matlab.ui.control.Button
        glovetypeDropDown               matlab.ui.control.DropDown
        ChoosetheglovetypeDropDownLabel  matlab.ui.control.Label
        NextButton                      matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        Tab2                            matlab.ui.container.Tab
        OutputLabel                     matlab.ui.control.Label
        DetectButton                    matlab.ui.control.Button
        Image3                          matlab.ui.control.Image
        defectDropDown                  matlab.ui.control.DropDown
        ChooseadefecttypeDropDownLabel  matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
    end

    
    methods (Access = private)
        
        function detect(app)
             if ~isempty(app.Image2)
                img = app.Image2.ImageSource;
                % set(app.UITable, 'Data', []);


                %existingData = get(app.UITable, 'Data');
                switch app.glovetypeDropDown.ValueIndex
                    case 1 % Nitrile
                        

                        obj = nitrile_detection(img);
                        switch app.defectDropDown.ValueIndex
                            case 1
                                [result1, message1,resultImage1] = obj.detectNitrileStain();
                                
                                if result1 ~= 0
                                    app.Image3.ImageSource = resultImage1;
                                    app.OutputLabel.Text = message1;
                                     
                                end
                            case 2
                                [result2, message2,resultImage2] = obj.detectMissingFinger();
                                
                                if result2 ~= 0
                                    app.Image3.ImageSource = resultImage2;
                                    app.OutputLabel.Text = message2;
                                end
                            case 3
                                [result3, message3, resultImage3] = obj.detectNitrileTearing();
                                 
                                if result3 ~= 0
                                    app.Image3.ImageSource = resultImage3;
                                    app.OutputLabel.Text = message3;
                                end
                        end
                    case 2 % Latex
                        

                        obj = latex_detection_code(img);
                        switch app.defectDropDown.ValueIndex
                            case 1
                                [result1, message1, resultImage1] = obj.detectLatexStain();
                               % combinedData = [existingData]; 
                                if result1 ~= 0
                                    
                                    app.Image3.ImageSource = resultImage1;
                                    app.OutputLabel.Text = message1;
                                   
                                end
                            case 2
                                [result2, message2,resultImage2] = obj.detectLatexMissingFinger();
                                 
                                if result2 ~= 0
                                    app.Image3.ImageSource = resultImage2;
                                    app.OutputLabel.Text = message2;
                                end
                            case 3
                                [result3, message3,resultImage3] = obj.detectLatexTearing();
                                
                                if result3 ~= 0
                                    app.Image3.ImageSource = resultImage3;
                                    app.OutputLabel.Text = message3;
                                end
                        end
                    case 3 % Cloth
                        

                        obj = cloth_detection_code(img);
                        switch app.defectDropDown.ValueIndex
                            case 1
                                [result1, message1,resultImage1] = obj.detectClothStain();
                                
                                if result1 ~= 0
                                    app.Image3.ImageSource = resultImage1;
                                    app.OutputLabel.Text = message1;
                                    
                                end
                            case 2
                                [result2, message2,resultImage2] = obj.detectClothMissingFinger();
                                
                                if result2 ~= 0
                                    app.Image3.ImageSource = resultImage2;
                                    app.OutputLabel.Text = message2;
                                end
                            case 3
                                [result3, message3,resultImage3] = obj.detectClothTears();
                                
                                if result3 ~= 0
                                    app.Image3.ImageSource = resultImage3;
                                    app.OutputLabel.Text = message3;
                                end
                             case 4
                                [result4, message4,resultImage4] = obj.detectClothSeam();
                                
                                if result4 ~= 0
                                    app.Image3.ImageSource = resultImage4;
                                    app.OutputLabel.Text = message4;
                                end
                            
                        end
                     case 4 % Sports Glove
                        
    
                        obj = sportsglove_detection_code(img);
                        switch app.defectDropDown.ValueIndex
                            case 1
                                [result1, message1,resultImage1] = obj.detectSportStain();
                                
                                if result1 ~= 0
                                    app.Image3.ImageSource = resultImage1;
                                    app.OutputLabel.Text = message1;
                                end
                            case 2
                                [result2, message2,resultImage2] = obj.detectSportMissingFinger();
                                
                                if result2 ~= 0
                                    app.Image3.ImageSource = resultImage2;
                                    app.OutputLabel.Text = message2;
                                end
                            case 3
                                [result3, message3,resultImage3] = obj.detectSportTear();
                                
                                if result3 ~= 0
                                   app.Image3.ImageSource = resultImage3;
                                   app.OutputLabel.Text = message3;
                                end
                        end

                    case 5 % Rubber Glove
                        
    
                        obj = rubber_detection_code(img);
                        switch app.defectDropDown.ValueIndex
                            case 1
                                [result1,message1,resultImage1] = obj.detectRubberStain();
                                
                                if result1 ~= 0
                                    app.Image3.ImageSource = resultImage1;
                                    app.OutputLabel.Text = message1;
                                end
                            case 2
                                [result2,message2, resultImage2] = obj.detectRubberMissingFinger();
                                
                                if result2 ~= 0
                                    app.Image3.ImageSource = resultImage2;
                                    app.OutputLabel.Text = message2;
                                end
                            case 3
                                [result3,message3, resultImage3] = obj.detectRubberTear();
                                
                                if result3 ~= 0
                                   app.Image3.ImageSource = resultImage3;
                                   app.OutputLabel.Text = message3;
                                end
                        end
                     otherwise
                        disp("invalid glove type selection");
                end
                
                % Display Result
                app.OutputLabel.Visible = "on";
                
            else
                disp('No image chosen!');

                app.OutputLabel.Text = "Error: Upload an valid image file to continue";
                app.DetectButton.Enable = "off";
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.jpg;*.jpeg;*.png', 'Image Files (*.bmp, *.jpg, *.jpeg, *.png)'}, 'Choose an image');
            
            if isempty(app.Image) && (isequal(filename, 0) || isequal(pathname, 0))
                app.DetectButton.Enable = "off";
                app.ContinueButton.Enable = "off";

                app.Image.ImageSource = "";
                app.Image2.ImageSource = "";
                app.ImageStateLabel.Text = "Error: Upload an valid image file to continue";
            elseif ~isempty(app.Image) & (isequal(filename, 0) || isequal(pathname, 0))
                return;
            else
                app.BrowseButton.Enable = "off";
                app.NextButton.Enable = "on";
                app.DetectButton.Enable = "on";
                app.OutputLabel.Text = strcat(pathname,filename);


                img = imread(fullfile(pathname, filename));
                app.Image.ImageSource = img;
                app.Image2.ImageSource = img;
            end


        end

        % Value changed function: glovetypeDropDown
        function glovetypeDropDownValueChanged(app, event)
            value = app.glovetypeDropDown.Value;
            
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab2;
            app.NextButton.Enable = "on";
        end

        % Button pushed function: DetectButton
        function DetectButtonPushed(app, event)
            detect(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Tab.Tag = 'nextButton';
            app.Image = uiimage(app.Tab);
            app.Image.Tag = 'firstImage';
            app.NextButton = uibutton(app.Tab, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.ChoosetheglovetypeDropDownLabel = uilabel(app.Tab);
            app.ChoosetheglovetypeDropDownLabel.Text = 'Choose the glove type';
            app.glovetypeDropDown = uidropdown(app.Tab);
            app.glovetypeDropDown.Items = {'Nitrile', 'Latex', 'Cloth', 'Sports Glove', 'Rubber'};
            app.glovetypeDropDown.ValueChangedFcn = createCallbackFcn(app, @glovetypeDropDownValueChanged, true);
            app.glovetypeDropDown.Tag = 'gloveType';
            app.glovetypeDropDown.Value = 'Nitrile';
            app.BrowseButton = uibutton(app.Tab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Tag = 'browseImage';
            app.BrowseButton.Text = 'Browse';
            app.BrowseForAnImageLabel = uilabel(app.Tab);
            app.BrowseForAnImageLabel.Text = 'Browse For An Image :';
            app.GloveDefectDetectionSystemLabel = uilabel(app.Tab);
            app.GloveDefectDetectionSystemLabel.Text = 'Glove Defect Detection System';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.Tab2.Tag = 'detectImage';
            app.Image2 = uiimage(app.Tab2);
            app.Image2.Tag = 'secondImage';
            app.ChooseadefecttypeDropDownLabel = uilabel(app.Tab2);
            app.ChooseadefecttypeDropDownLabel.Text = 'Choose a defect type';
            app.defectDropDown = uidropdown(app.Tab2);
            app.defectDropDown.Items = {'Stain', 'Missing Finger', 'Hole', 'Seam'};
            app.defectDropDown.Tag = 'defectDropdown';
            app.defectDropDown.Value = 'Stain';
            app.Image3 = uiimage(app.Tab2);
            app.Image3.Tag = 'outputImage';
            app.DetectButton = uibutton(app.Tab2, 'push');
            app.DetectButton.ButtonPushedFcn = createCallbackFcn(app, @DetectButtonPushed, true);
            app.DetectButton.Tag = 'detectDefect';
            app.DetectButton.Text = 'Detect';
            app.OutputLabel = uilabel(app.Tab2);
            app.OutputLabel.Tag = 'resultLabel';
            app.OutputLabel.Text = 'Output';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end